classdef yinyue < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        Menu                     matlab.ui.container.Menu
        Menu_2                   matlab.ui.container.Menu
        Menu_3                   matlab.ui.container.Menu
        TabGroup                 matlab.ui.container.TabGroup
        Tab                      matlab.ui.container.Tab
        DFTButton                matlab.ui.control.Button
        Label_8                  matlab.ui.control.Label
        Label_9                  matlab.ui.control.Label
        Panel_2                  matlab.ui.container.Panel
        D0Spinner                matlab.ui.control.Spinner
        Label_6                  matlab.ui.control.Label
        exechlbtn                matlab.ui.control.Button
        PFBtnGroup               matlab.ui.container.ButtonGroup
        Ideal_LPF                matlab.ui.control.RadioButton
        Gaussian_LPF             matlab.ui.control.RadioButton
        ButterWorth_LPF          matlab.ui.control.RadioButton
        hlfitertype              matlab.ui.container.ButtonGroup
        Button_5                 matlab.ui.control.RadioButton
        Button_4                 matlab.ui.control.RadioButton
        Panel                    matlab.ui.container.Panel
        execspacefilterbtn       matlab.ui.control.Button
        FilterTypesBtnGroup      matlab.ui.container.ButtonGroup
        Non_Linear               matlab.ui.control.RadioButton
        Rec_Avrg                 matlab.ui.control.RadioButton
        Gaussian                 matlab.ui.control.RadioButton
        Gaussian_La              matlab.ui.control.RadioButton
        HistOriImage             matlab.ui.control.UIAxes
        HistImage                matlab.ui.control.UIAxes
        Tab_2                    matlab.ui.container.Tab
        Panel_4                  matlab.ui.container.Panel
        gammaSlider              matlab.ui.control.Slider
        gammaSliderLabel         matlab.ui.control.Label
        execotherprocbtn         matlab.ui.control.Button
        lightstepper             matlab.ui.control.Slider
        Label_10                 matlab.ui.control.Label
        Panel_3                  matlab.ui.container.Panel
        INDEXQNSpinner           matlab.ui.control.Spinner
        INDEXQNSpinnerLabel      matlab.ui.control.Label
        SmoothType               matlab.ui.control.DropDown
        DropDown_2Label          matlab.ui.control.Label
        SmoothfliterSize         matlab.ui.control.Spinner
        Label_11                 matlab.ui.control.Label
        execcolorbtn             matlab.ui.control.Button
        ColorimgProcessWayGroup  matlab.ui.container.ButtonGroup
        Button_12                matlab.ui.control.RadioButton
        RGBINDEXButton_2         matlab.ui.control.RadioButton
        RGBgrayINDEXButton       matlab.ui.control.RadioButton
        HSIRGBButton             matlab.ui.control.RadioButton
        RGBHSIButton             matlab.ui.control.RadioButton
        Button_10                matlab.ui.control.RadioButton
        Button_9                 matlab.ui.control.RadioButton
        Button_8                 matlab.ui.control.RadioButton
        Button_7                 matlab.ui.control.RadioButton
        Button_6                 matlab.ui.control.RadioButton
        Tab_3                    matlab.ui.container.Tab
        flterway                 matlab.ui.container.Panel
        recoverfilterchk         matlab.ui.control.CheckBox
        ShiftAngle_2             matlab.ui.control.Spinner
        Label_18                 matlab.ui.control.Label
        ShiftRange_2             matlab.ui.control.Spinner
        Label_17                 matlab.ui.control.Label
        OrderNum                 matlab.ui.control.Spinner
        Label_16                 matlab.ui.control.Label
        FliterWayBtnGroup        matlab.ui.container.ButtonGroup
        ActiveFilter             matlab.ui.control.RadioButton
        SelfAdjustFilter         matlab.ui.control.RadioButton
        MinFilter                matlab.ui.control.RadioButton
        MaxFilter                matlab.ui.control.RadioButton
        OrderFilter              matlab.ui.control.RadioButton
        GeomFilter               matlab.ui.control.RadioButton
        HarmoFilter              matlab.ui.control.RadioButton
        MeanValueFilter          matlab.ui.control.RadioButton
        MedFilter                matlab.ui.control.RadioButton
        Panel_6                  matlab.ui.container.Panel
        ShiftAngle               matlab.ui.control.Spinner
        Label_15                 matlab.ui.control.Label
        ShiftRange               matlab.ui.control.Spinner
        Label_14                 matlab.ui.control.Label
        motionEnable             matlab.ui.control.CheckBox
        noiseevecbtn             matlab.ui.control.Button
        Panel_5                  matlab.ui.container.Panel
        chengxingstepper         matlab.ui.control.Spinner
        Label_13                 matlab.ui.control.Label
        gaosistepper1            matlab.ui.control.Spinner
        Spinner_3Label           matlab.ui.control.Label
        gaosistepper2            matlab.ui.control.Spinner
        Label_12                 matlab.ui.control.Label
        jiaoyanstepper           matlab.ui.control.Spinner
        SpinnerLabel             matlab.ui.control.Label
        noisechengxingchk        matlab.ui.control.CheckBox
        noisegaosichk            matlab.ui.control.CheckBox
        noisebosongchk           matlab.ui.control.CheckBox
        noisejiaoyanchk          matlab.ui.control.CheckBox
        Tab_4                    matlab.ui.container.Tab
        Button_11                matlab.ui.control.Button
        PrecompressionRatio      matlab.ui.control.EditField
        Label_24                 matlab.ui.control.Label
        ForeseeCompressSize      matlab.ui.control.EditField
        Label_23                 matlab.ui.control.Label
        OriginFileSize           matlab.ui.control.EditField
        Label_22                 matlab.ui.control.Label
        HuffcompressionRatio     matlab.ui.control.EditField
        Label_20                 matlab.ui.control.Label
        HuffumanCompressSize     matlab.ui.control.EditField
        Label_21                 matlab.ui.control.Label
        Label_19                 matlab.ui.control.Label
        CompressCodeTable        matlab.ui.control.Table
        DFTLabel                 matlab.ui.control.Label
        setnewnowimgbtn          matlab.ui.control.Button
        Label                    matlab.ui.control.Label
        changeoriimagebtn        matlab.ui.control.Button
        NowImage                 matlab.ui.control.UIAxes
        OriImage                 matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        tabledata = [];
        userchoosefilename1 = "";
         
        userIm1 = [];
        preresultimage; % Description
        ofilesize % Description
    end
    
    methods (Access = private)
        
        function path = getfilename(app)
            [filename,path] = uigetfile({'*.jpg';'*.png';'*.tif';'*.bmp'});
            app.userchoosefilename1 = filename;
            if(path ~= 0)
                path= fullfile(path,filename);
            else
                path = 'lena.png';
            end
        end
        
        function init(app)
            setneworiimage(app,"lena.png");
            %app.OriginFileSize.Value = double(app.ofilesize)/1000 + "kB";                            
        end
        
        function [filesize,size] = getfileinfo(~,filename)
            info = imfinfo(filename);
            filesize = info.FileSize;
            size = info.Width*info.Height*info.BitDepth;
        end
        
        function applyendimage(app,g)
            cla(app.HistImage,'reset');
            colorhistogram(app.HistImage,g);
            imshow(g,'Parent',app.NowImage);
            app.preresultimage=g;
        end
        
        function g = myimfliter(~,f,a)
            switch(a)
                case "高斯-拉普拉斯"
                    w = fspecial('log');
                    g = imfilter(f,w); 
                case "高斯"
                    w = fspecial('gaussian',[3 3],0.5);
                    g = imfilter(f,w); 
                case "矩形平均"
                    w = fspecial('average',[3 3]);
                    g = imfilter(f,w); 
                case "中位数滤波器"
                    g = medfilt2(f);
            end
        end  
        
        function I = rgb2grayifneed(~,im)
            if(ifcolorimage(im))
                I=rgb2gray(im);
            else
                I=im;
            end
        end
        
        function I = beforecompress(~,im)
            if(ifcolorimage(im))
                I=cat(1,im(:,:,1),im(:,:,2),im(:,:,3));
            else
                I=im;
            end
        end
        
        function I = GetNowGrayImage(app)
            I=im2double(rgb2grayifneed(app,app.userIm1));
        end
        
        function results = GetD0SettingValue(app)
            results=app.D0Spinner.Value;
        end
        
        function setneworiimage(app,filename)
            app.userchoosefilename1=filename;
            app.userIm1 = imread(filename);
            imshow(app.userIm1,'Parent',app.OriImage);
            colorhistogram(app.HistOriImage,app.userIm1);
            [height, width, dim] = size(app.userIm1);
            app.ofilesize=height*width*dim;
            app.OriginFileSize.Value=app.ofilesize/1024+"kB";
        end
        
        function setneworiimageforimage(app,image)
            app.userchoosefilename1="";
            app.userIm1 = image;
            imshow(app.userIm1,'Parent',app.OriImage);
            colorhistogram(app.HistOriImage,app.userIm1);
            [height, width, dim] = size(app.userIm1);
            app.ofilesize=height*width*dim;
            app.OriginFileSize.Value=app.ofilesize/1024+"kB";
        end
        
        function d=grayhistogram(~,I)%构造histogram函数
            %此函数用于均衡化
            J=I;
            [m,n]=size(I);      %确定矩阵大小
            area=m*n;
            a=zeros(1,256);     %产生1*256的零矩阵a,用来存放原始图像各个灰度值的个数
            b=zeros(1,256);
            for i=1:m           %记录各个灰度值的个数
                for j=1:n
                    d=I(i,j)+1;   %获取(i,j)位置的灰度值(注意：灰度值为0-255，对应矩阵的1-256）
                    a(1,d)=a(1,d)+1;    %矩阵a上对应灰度值的计数+1
                end
            end
            for i=1:256         %均衡化
                sum=0;
                for j=1:i
                    sum=sum+a(1,j);
                end
                b(1,i)=sum*255/area;
            end
            for i=1:m           %用均衡化后的数据代替原位置的数据
                for j=1:n
                    d=J(i,j)+1;
                    J(i,j)=b(1,d);
                end
            end
            d = J;
        end
        
        function out = coloraverage(app,im)
            if(ifcolorimage(im))
                %此函数用于彩图均衡化
                im=rgb2hsi(im2uint8(im));
                I1=im(:,:,1);
                I2=im(:,:,2);
                I3=im(:,:,3);
                I3=im2double(grayhistogram(app,im2uint8(I3)));
                
                out=cat(3,I1,I2,I3);  %cat用于恢复成彩图
                out=hsi2rgb(out);
            else
                out=grayhistogram(app,im);
            end
        end
        
        function out = BrightRGB(~,im,strength)
            %此函数用于增强图片亮度
            %im为输入图片，strength为增强的倍率
            %增强倍率默认为1（也就是不增强
            if(ifcolorimage(im))
                R = im(:,:,1)*strength;
                G = im(:,:,2)*strength;
                B = im(:,:,3)*strength;
                out = cat(3,R,G,B);
            else
                out=im(:,:)*strength;
            end
        end
        
        function J = addnoise(app,im)
            im=im2double(im);           
            J=im;         
            if(app.noisejiaoyanchk.Value)
                J=imnoise(J,'salt & pepper', app.jiaoyanstepper.Value);%椒盐噪声
            end
            if(app.noisebosongchk.Value)
                J=imnoise(J,'poisson');%泊松噪声
            end
            if(app.noisegaosichk.Value)
                J=imnoise(J,'gaussian', app.gaosistepper1.Value,app.gaosistepper2.Value);%高斯噪声
            end
            if(app.noisechengxingchk.Value)
                J=imnoise(im,'speckle',app.chengxingstepper.Value);
            end          
        end
        
        function J = active(~,im,range,angle)  
            %LEN=25;%运动模糊参数
            %THETA=20;%运动模糊参数
            PSF=fspecial('motion',range,angle);
            J=imfilter(im,PSF,'conv','circular');%运动模糊
             
        end
        function K = fliterkinds(app,J,ordernum)
            if(ifcolorimage(J))
                K = J;
                for c = 1 : 3
                    K(:, :, c) = fliterkindsgray(app,J(:, :, c),ordernum);
                end
            else
                K=fliterkindsgray(app,J,ordernum);
            end
        end
        function K = fliterkindsgray(app,J,ordernum)
            w1=fspecial('average',3);%均值滤波器
            Q=ordernum;%逆谐波滤波器阶数
            
            fliterway = app.FliterWayBtnGroup.SelectedObject.Text;
            switch fliterway
                case "二维中值滤波"
                    K=medfilt2(J,[3,3]);%二维中值滤波
                case "算数均值滤波"
                    K=imfilter(J,w1);%算数均值滤波
                case "几何均值滤波"
                    K=exp(imfilter(log(J),w1));%几何均值滤波
                case "逆谐波均值滤波"
                    K1=imfilter(J .^ (Q+1) , w1);
                    K2=imfilter(J .^ Q, w1);
                    K=K1./K2;%逆谐波均值滤波
                case "统计排序滤波"
                    domain=[0 1 1 0; 1 1 1 1; 1 1 1 1; 0 1 1 0];
                    K=ordfilt2(J,6,domain);%统计排序滤波
                case "最大值滤波"
                    K=ordfilt2(J,1,ones(3));%最大值滤波
                case "最小值滤波"
                    K=ordfilt2(J,9,ones(3));%最小值滤波
                case "自适应维纳滤波"
                    K=wiener2(J,[5,5]);%自适应维纳滤波
                case "运动模糊维纳滤波"
                    K=deconvwnr(J,fspecial('motion',app.ShiftRange_2.Value,app.ShiftAngle_2.Value),0.0001);%运动模糊维纳滤波修复
            end
           
        end
        function makecode(app,sc, codeword)
            global CODE
            if isa(sc, 'cell')
                makecode(app,sc{1}, [codeword, 0]);
                makecode(app,sc{2}, [codeword, 1]);
            else
                CODE{sc} = char('0' + codeword);
            end
        end
        
        function s = reduce(~,p)
            s = cell(length(p), 1);
            for i = 1:length(p)
                s{i} = i;
            end
            while numel(s) > 2
                [p, i] = sort(p);
                p(2) = p(1) + p(2);
                p(1) = [];
                s = s(i);
                s{2} = {s{1}, s{2}};
                s(1) = [];
            end
        end
        function CODE=huffman(app,p)
            %huffman为源符号建立一个可变长度的Huffman编码
            %对于符号概率向量p在单元数组CODE中的二进制字符串返回一个Huffman编码
            %在CODE中的每个元素对应一个字符，它的概率与指数p符合。
            
            %检查输入参数
            %narginchk(1, 1);
            %if (~ismatrix(p)) || (min(size(p))>1) || ~isreal(p) || ~isnumeric(p)
            %     error('P must be a real numeric vector.');
            %end
            
            %全局变量存在于所有递归函数'makecode'
            global CODE;
            CODE=cell(length(p),1);%初始的全局单位数组
            
            if length(p)>1%当多余一个符号时
                p=p/sum(p);%标准化的输入概率
                s=reduce(app,p);%简约的源符号
                makecode(app,s,[]);%递归产生编码
            else
                CODE={'1'};%平凡的一个符号例子
            end
        end
        
        function huff(app,im)
            src = im2uint8(beforecompress(app,im));
            [m, n] = size(src);
            
            % I = src(:).'; % [bug] curel
            
            Im = zeros(1, m*n);
            for i = 1 : m
                for j = 1 : n
                    Im((i-1)*n+j) = src(i, j);
                end
            end
            
            %I = [2, 3, 4, 2, 3, 2, 4, 4, 2, 2, 1, 2, 1, 1, 2, 2];
            
            [~, siz] = size(Im);
            
            hashmap = zeros(1, 256);
            
            for i = 1 : siz
                hashmap(Im(i)+1) = hashmap(Im(i)+1) + 1;
            end
            
            huff = huffman(app,hashmap);
            
            % reverse huffman table
            strmap = containers.Map;
            
            for i = 1 : 256
                rev = cell2mat(huff(i));
                strmap(rev) = i;
            end
            
            
            % Compress Algorithm
            
            compressed = cell(siz, 1);
            filesize = 0;
            app.tabledata = zeros(siz,2);
            for i = 1 : siz
                compressed(i) = huff(Im(i)+1);
                filesize = filesize + length(compressed(i));
            end
           
            app.HuffcompressionRatio.Value = (filesize/(8*app.ofilesize))*100 + "%";
            disp("*******size************");
            disp(size(compressed));
            disp("********file size*************");
            disp(filesize);
            disp("*********origin size *8************");
            disp(app.ofilesize*8);
            disp("*********************");
            app.HuffumanCompressSize.Value = (filesize / (8*1024)) + "kB";
            
            
            app.tabledata = cell2table(compressed);
         
            % compressed array is the result
            % Uncompress Algorithm
            
            uncompressed = zeros(siz, 1);
            result = zeros(m, n);
            
            for i = 1 : siz
                rev = cell2mat(compressed(i));
                uncompressed(i) = strmap(rev) - 1;
            end
            
            
            for i = 1 : m
                for j = 1 : n
                    result(i,:) = uncompressed((1:n)+(i-1)*n);
                end
            end
            
            result = uint8(result);
            
            % uncompressed array is the result
            
            %title('压缩前');
            
           % title('解压后');
        end
        function compresscode(app,im)
            src = im2uint8(beforecompress(app,im));
            [M, N] = size(src);
            
            Im = zeros(1, M*N);
            for i = 1 : M
                for j = 1 : N
                    Im((i-1)*N+j) = src(i, j);
                end
            end
            
            % Inner Process Start
            
            a = 1/2;
            
            [m, n] = size(Im); % m must be 1
            compressed = zeros(m, n);
            compressed(1:2) = Im(1:2);
            filesize = 0;
            for i = 3 : n
                compressed(i) = Im(i) - round(a*(Im(i-2) + Im(i-1)));
                filesize = max(compressed(i),filesize);
            end
            total = log2(filesize)*n;
            disp("*******size************");
            disp(size(compressed));
            disp("*********fore************");
            disp(total);
            disp("*********************");
            disp(app.ofilesize*8);
            disp("*********************");
            app.PrecompressionRatio.Value = (total/(app.ofilesize*8))*100 + "%";
            app.ForeseeCompressSize.Value = total/(8*1024) + "kB";
          
            %app.CompressCodeTable.Data = tabledata;
        
            temp = array2table(compressed');
            app.tabledata = [app.tabledata temp];
            app.CompressCodeTable.Data = app.tabledata;
       
            uncompressed = zeros(m, n);
            uncompressed(1:2) = Im(1:2);
            for i = 3 : n
                uncompressed(i) = round(a*(uncompressed(i-2) + uncompressed(i-1))) + compressed(i);
            end
            
            % Inner Process End
            
            result = zeros(M, N);
            for i = 1 : M
                for j = 1 : N
                    result(i,:) = uncompressed((1:N)+(i-1)*N);
                end
            end
            result = uint8(result);
           
            %imshow(result,'Parent',app.hand);
           
        end
        
        %二维DFT
        function F = toDFT(~,im)
            f=im2double(im);
            if(ifcolorimage(f))
                f = rgb2gray(f);
            end
            F = real(fftshift(fft2(f)));
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            init(app);
        end

        % Button pushed function: changeoriimagebtn
        function changeoriimagebtnPushed(app, event)
            path= getfilename(app);
            if(path ~= 0)
                setneworiimage(app,path);
            end
        end

        % Button pushed function: execspacefilterbtn
        function execspacefilterbtnButtonPushed(app, event)
            flitertypeText = app.FilterTypesBtnGroup.SelectedObject.Text;
            g = myimfliter(app,GetNowGrayImage(app),flitertypeText);
            applyendimage(app,g);
        end

        % Button pushed function: exechlbtn
        function exechlbtnButtonPushed(app, event)
            flitertypeText=app.hlfitertype.SelectedObject.Text;
            fliterwayText=app.PFBtnGroup.SelectedObject.Text;
            D0=GetD0SettingValue(app);
            img=GetNowGrayImage(app);
            Ig=[];
            ishigh=flitertypeText=="高通滤波器";
            switch(fliterwayText)
                case "理想"
                    if(ishigh)
                        Ig=Ideal_high_pass_filter(img,D0);
                    else
                        Ig=Ideal_low_pass_filter(img,D0);
                    end
                case "巴特沃斯"
                    if(ishigh)
                        Ig=Butterworth_high_pass_filter(img,D0);
                    else
                        Ig=Butterworth_low_pass_filter(img,D0);
                    end
                case "高斯"
                    if(ishigh)
                        Ig=Gaussian_high_pass_filter(img,D0);
                    else
                        Ig=Gaussian_low_pass_filter(img,D0);
                    end
            end
            applyendimage(app,Ig);
        end

        % Button pushed function: setnewnowimgbtn
        function setnewnowimgbtnButtonPushed(app, event)
            setneworiimageforimage(app,app.preresultimage);
        end

        % Button pushed function: DFTButton
        function DFTButtonPushed(app, event)
            img=toDFT(app,app.userIm1);
            applyendimage(app,img);
            imshow(log(img+1),'Parent',app.NowImage);
        end

        % Button pushed function: execcolorbtn
        function execcolorbtnButtonPushed(app, event)
            currentWay = app.ColorimgProcessWayGroup.SelectedObject.Text;
            I=app.userIm1;
            switch(currentWay)
                case "均衡化"
                    out1 = coloraverage(app,I);
                case "边缘检测"
                    out1 = rgbEdgeDetection(I); 
                case "平滑"
                    out1 = Color_image_smoothing(im2double(I), ...
                        app.SmoothType.Value,app.SmoothfliterSize.Value);
                case "灰度图->伪彩色图（一般）"
                    if(ifcolorimage(I)==false)
                        out1 =Gray_level_to_color_transformation_processing(I);
                    else
                        out1=I;
                        msgbox("已经是彩图");
                    end
                case "灰度图->伪彩色图（分层）"
                    if(ifcolorimage(I)==false)
                        X =grayslice(I,app.INDEXQNSpinner.Value);
                        out1=ind2rgb(X,jet(app.INDEXQNSpinner.Value));
                    else
                        out1=I;
                        msgbox("已经是彩图");
                    end 
                case "彩色图->灰度图"
                    out1 =im2gray(I);
                case "RGB->HSI"
                    if(ifcolorimage(I))
                        out1=rgb2hsi(I);
                    else
                        out1=I;
                        msgbox("不能对灰度图执行该操作");
                    end
                case "HSI->RGB"
                    if(ifcolorimage(I))
                        out1=hsi2rgb(I);
                    else
                        out1=I;
                        msgbox("不能对灰度图执行该操作");
                    end
                case "RGB/gray->INDEX"
                    if(ifcolorimage(I))
                        [X, map] = rgb2ind(I, ...
                            app.INDEXQNSpinner.Value, 'nodither');
                        out1=ind2rgb(X,map);
                    else
                        [X, map] = gray2ind(I,min(256,app.INDEXQNSpinner.Value));
                        out1=ind2gray(X,map);
                    end
                case "RGB->INDEX（抖动）"
                    if(ifcolorimage(I))
                        [X, map] = rgb2ind(I, ...
                            app.INDEXQNSpinner.Value, 'dither');
                        out1=ind2rgb(X,map);
                    else
                        out1=I;
                        msgbox("不能对灰度图执行该操作");
                    end
            end
            applyendimage(app,out1);
        end

        % Button pushed function: execotherprocbtn
        function execotherprocbtnButtonPushed(app, event)
            I=BrightRGB(app,app.userIm1,1+app.lightstepper.Value/100.0);
            if(app.gammaSlider.Value~=1)
                I=imadjust(im2double(I),[0,1],[0,1],app.gammaSlider.Value);
            end
            applyendimage(app,I);
            
        end

        % Menu selected function: Menu_2
        function Menu_2Selected(app, event)
            msgbox(["MatlabImageToolsApp  Copyright (C) 2022"
            "This program comes with ABSOLUTELY NO WARRANTY;"
            "This is free software, and you are welcome to redistribute it under certain conditions; "
            "for details see https://www.gnu.org/licenses/gpl-3.0.html"])
        end

        % Menu selected function: Menu_3
        function Menu_3Selected(app, event)
            web("https://github.com/yinyue200/MatlabImageToolsApp")
        end

        % Button pushed function: noiseevecbtn
        function noiseevecbtnButtonPushed(app, event)
            im = addnoise(app,app.userIm1);
            if(app.motionEnable.Value)
                im = active(app,im,app.ShiftRange.Value,app.ShiftAngle.Value);
            end
            if(app.recoverfilterchk.Value)
                im = fliterkinds(app,im,app.OrderNum.Value);
            end
            applyendimage(app,im);
        end

        % Button pushed function: Button_11
        function Button_11Pushed(app, event)
            app.Button_11.Enable='off';
            app.CompressCodeTable.Enable='off';
            huff(app,app.userIm1);
            compresscode(app,app.userIm1);
            app.CompressCodeTable.Enable='on';
            app.Button_11.Enable='on';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Menu = uimenu(app.UIFigure);
            app.Menu.Text = '关于';
            app.Menu_2 = uimenu(app.Menu);
            app.Menu_2.MenuSelectedFcn = createCallbackFcn(app, @Menu_2Selected, true);
            app.Menu_2.Text = '关于信息';
            app.Menu_3 = uimenu(app.Menu);
            app.Menu_3.MenuSelectedFcn = createCallbackFcn(app, @Menu_3Selected, true);
            app.Menu_3.Text = '网站';
            app.OriImage = uiaxes(app.UIFigure);
            app.NowImage = uiaxes(app.UIFigure);
            app.changeoriimagebtn = uibutton(app.UIFigure, 'push');
            app.changeoriimagebtn.ButtonPushedFcn = createCallbackFcn(app, @changeoriimagebtnPushed, true);
            app.changeoriimagebtn.Text = {'更换待处理图像'; ''};
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '待处理图像';
            app.setnewnowimgbtn = uibutton(app.UIFigure, 'push');
            app.setnewnowimgbtn.ButtonPushedFcn = createCallbackFcn(app, @setnewnowimgbtnButtonPushed, true);
            app.setnewnowimgbtn.Text = {'设置为新的待处理图像'; ''};
            app.DFTLabel = uilabel(app.UIFigure);
            app.DFTLabel.Text = '处理后图像/DFT频谱';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = '灰度图像处理';
            app.HistImage = uiaxes(app.Tab);
            xlabel(app.HistImage, 'X')
            ylabel(app.HistImage, 'Y')
            zlabel(app.HistImage, 'Z')
            app.HistOriImage = uiaxes(app.Tab);
            xlabel(app.HistOriImage, 'X')
            ylabel(app.HistOriImage, 'Y')
            zlabel(app.HistOriImage, 'Z')
            app.Panel = uipanel(app.Tab);
            app.Panel.Title = '空间滤波';
            app.FilterTypesBtnGroup = uibuttongroup(app.Panel);
            app.FilterTypesBtnGroup.Title = '滤波器种类';
            app.Gaussian_La = uiradiobutton(app.FilterTypesBtnGroup);
            app.Gaussian_La.Text = '高斯-拉普拉斯';
            app.Gaussian_La.Value = true;
            app.Gaussian = uiradiobutton(app.FilterTypesBtnGroup);
            app.Gaussian.Text = '高斯';
            app.Rec_Avrg = uiradiobutton(app.FilterTypesBtnGroup);
            app.Rec_Avrg.Text = '矩形平均';
            app.Non_Linear = uiradiobutton(app.FilterTypesBtnGroup);
            app.Non_Linear.Text = '中位数滤波器';
            app.execspacefilterbtn = uibutton(app.Panel, 'push');
            app.execspacefilterbtn.ButtonPushedFcn = createCallbackFcn(app, @execspacefilterbtnButtonPushed, true);
            app.execspacefilterbtn.Text = '执行';
            app.Panel_2 = uipanel(app.Tab);
            app.Panel_2.Title = '低通/高通滤波器';
            app.hlfitertype = uibuttongroup(app.Panel_2);
            app.hlfitertype.Title = '滤波器种类';
            app.Button_4 = uiradiobutton(app.hlfitertype);
            app.Button_4.Text = '低通滤波器';
            app.Button_4.Value = true;
            app.Button_5 = uiradiobutton(app.hlfitertype);
            app.Button_5.Text = '高通滤波器';
            app.PFBtnGroup = uibuttongroup(app.Panel_2);
            app.PFBtnGroup.Title = '滤波方式';
            app.ButterWorth_LPF = uiradiobutton(app.PFBtnGroup);
            app.ButterWorth_LPF.Text = '巴特沃斯';
            app.ButterWorth_LPF.Value = true;
            app.Gaussian_LPF = uiradiobutton(app.PFBtnGroup);
            app.Gaussian_LPF.Text = '高斯';
            app.Ideal_LPF = uiradiobutton(app.PFBtnGroup);
            app.Ideal_LPF.Text = '理想';
            app.exechlbtn = uibutton(app.Panel_2, 'push');
            app.exechlbtn.ButtonPushedFcn = createCallbackFcn(app, @exechlbtnButtonPushed, true);
            app.exechlbtn.Text = '执行';
            app.Label_6 = uilabel(app.Panel_2);
            app.Label_6.Text = '截止频率半径';
            app.D0Spinner = uispinner(app.Panel_2);
            app.D0Spinner.Value = 50;
            app.Label_9 = uilabel(app.Tab);
            app.Label_9.Text = '处理后直方图';
            app.Label_8 = uilabel(app.Tab);
            app.Label_8.Text = {'待处理直方图'; ''};
            app.DFTButton = uibutton(app.Tab, 'push');
            app.DFTButton.ButtonPushedFcn = createCallbackFcn(app, @DFTButtonPushed, true);
            app.DFTButton.Text = 'DFT';
            app.Tab_2 = uitab(app.TabGroup);
            app.Tab_2.Title = '彩色图像处理';
            app.Panel_3 = uipanel(app.Tab_2);
            app.Panel_3.Title = '彩图处理';
            app.ColorimgProcessWayGroup = uibuttongroup(app.Panel_3);
            app.ColorimgProcessWayGroup.Title = '处理方式';
            app.Button_6 = uiradiobutton(app.ColorimgProcessWayGroup);
            app.Button_6.Text = '均衡化';
            app.Button_6.Value = true;
            app.Button_7 = uiradiobutton(app.ColorimgProcessWayGroup);
            app.Button_7.Text = '边缘检测';
            app.Button_8 = uiradiobutton(app.ColorimgProcessWayGroup);
            app.Button_8.Text = '平滑';
            app.Button_9 = uiradiobutton(app.ColorimgProcessWayGroup);
            app.Button_9.Text = '灰度图->伪彩色图（一般）';
            app.Button_10 = uiradiobutton(app.ColorimgProcessWayGroup);
            app.Button_10.Text = '彩色图->灰度图';
            app.RGBHSIButton = uiradiobutton(app.ColorimgProcessWayGroup);
            app.RGBHSIButton.Text = 'RGB->HSI';
            app.HSIRGBButton = uiradiobutton(app.ColorimgProcessWayGroup);
            app.HSIRGBButton.Text = 'HSI->RGB';
            app.RGBgrayINDEXButton = uiradiobutton(app.ColorimgProcessWayGroup);
            app.RGBgrayINDEXButton.Text = 'RGB/gray->INDEX';
            app.RGBINDEXButton_2 = uiradiobutton(app.ColorimgProcessWayGroup);
            app.RGBINDEXButton_2.Text = 'RGB->INDEX（抖动）';
            app.Button_12 = uiradiobutton(app.ColorimgProcessWayGroup);
            app.Button_12.Text = '灰度图->伪彩色图（分层）';
            app.execcolorbtn = uibutton(app.Panel_3, 'push');
            app.execcolorbtn.ButtonPushedFcn = createCallbackFcn(app, @execcolorbtnButtonPushed, true);
            app.execcolorbtn.Text = '执行';
            app.Label_11 = uilabel(app.Panel_3);
            app.Label_11.Text = '平滑滤波器大小';
            app.SmoothfliterSize = uispinner(app.Panel_3);
            app.SmoothfliterSize.Limits = [2 Inf];
            app.SmoothfliterSize.Value = 2;
            app.DropDown_2Label = uilabel(app.Panel_3);
            app.DropDown_2Label.Text = '平滑类型';
            app.SmoothType = uidropdown(app.Panel_3);
            app.SmoothType.Items = {'rgb', 'hsi'};
            app.SmoothType.Value = 'rgb';
            app.INDEXQNSpinnerLabel = uilabel(app.Panel_3);
            app.INDEXQNSpinnerLabel.Text = 'INDEX Q/N';
            app.INDEXQNSpinner = uispinner(app.Panel_3);
            app.INDEXQNSpinner.Limits = [2 65535];
            app.INDEXQNSpinner.Value = 256;
            app.Panel_4 = uipanel(app.Tab_2);
            app.Panel_4.Title = '其它调整';
            app.Label_10 = uilabel(app.Panel_4);
            app.Label_10.Text = '亮度';
            app.lightstepper = uislider(app.Panel_4);
            app.lightstepper.Limits = [-100 100];
            app.execotherprocbtn = uibutton(app.Panel_4, 'push');
            app.execotherprocbtn.ButtonPushedFcn = createCallbackFcn(app, @execotherprocbtnButtonPushed, true);
            app.execotherprocbtn.Text = '执行';
            app.gammaSliderLabel = uilabel(app.Panel_4);
            app.gammaSliderLabel.Text = 'gamma';
            app.gammaSlider = uislider(app.Panel_4);
            app.gammaSlider.Limits = [0 3];
            app.gammaSlider.Value = 1;
            app.Tab_3 = uitab(app.TabGroup);
            app.Tab_3.Title = '图像复原与重建';
            app.Panel_5 = uipanel(app.Tab_3);
            app.Panel_5.Title = '噪声';
            app.noisejiaoyanchk = uicheckbox(app.Panel_5);
            app.noisejiaoyanchk.Text = '椒盐';
            app.noisebosongchk = uicheckbox(app.Panel_5);
            app.noisebosongchk.Text = '泊松';
            app.noisegaosichk = uicheckbox(app.Panel_5);
            app.noisegaosichk.Text = '高斯';
            app.noisechengxingchk = uicheckbox(app.Panel_5);
            app.noisechengxingchk.Text = '乘性';
            app.SpinnerLabel = uilabel(app.Panel_5);
            app.SpinnerLabel.Text = '椒盐噪声密度';
            app.jiaoyanstepper = uispinner(app.Panel_5);
            app.jiaoyanstepper.Step = 0.01;
            app.jiaoyanstepper.Limits = [0 1];
            app.jiaoyanstepper.Value = 0.05;
            app.Label_12 = uilabel(app.Panel_5);
            app.Label_12.Text = '高斯噪声方差';
            app.gaosistepper2 = uispinner(app.Panel_5);
            app.gaosistepper2.Step = 0.01;
            app.gaosistepper2.Value = 0.01;
            app.Spinner_3Label = uilabel(app.Panel_5);
            app.Spinner_3Label.Text = '高斯噪声均值';
            app.gaosistepper1 = uispinner(app.Panel_5);
            app.gaosistepper1.Step = 0.01;
            app.Label_13 = uilabel(app.Panel_5);
            app.Label_13.Text = '乘性噪声方差';
            app.chengxingstepper = uispinner(app.Panel_5);
            app.chengxingstepper.Step = 0.01;
            app.chengxingstepper.Value = 0.05;
            app.noiseevecbtn = uibutton(app.Tab_3, 'push');
            app.noiseevecbtn.ButtonPushedFcn = createCallbackFcn(app, @noiseevecbtnButtonPushed, true);
            app.noiseevecbtn.Text = '执行';
            app.Panel_6 = uipanel(app.Tab_3);
            app.motionEnable = uicheckbox(app.Panel_6);
            app.motionEnable.Text = '运动模糊';
            app.Label_14 = uilabel(app.Panel_6);
            app.Label_14.Text = '偏移范围';
            app.ShiftRange = uispinner(app.Panel_6);
            app.ShiftRange.Limits = [2 Inf];
            app.ShiftRange.Value = 2;
            app.Label_15 = uilabel(app.Panel_6);
            app.Label_15.Text = '偏移角度';
            app.ShiftAngle = uispinner(app.Panel_6);
            app.ShiftAngle.Limits = [-180 180];
            app.flterway = uipanel(app.Tab_3);
            app.FliterWayBtnGroup = uibuttongroup(app.flterway);
            app.MedFilter = uiradiobutton(app.FliterWayBtnGroup);
            app.MedFilter.Text = '二维中值滤波';
            app.MedFilter.Value = true;
            app.MeanValueFilter = uiradiobutton(app.FliterWayBtnGroup);
            app.MeanValueFilter.Text = '算数均值滤波';
            app.HarmoFilter = uiradiobutton(app.FliterWayBtnGroup);
            app.HarmoFilter.Text = '逆谐波均值滤波';
            app.GeomFilter = uiradiobutton(app.FliterWayBtnGroup);
            app.GeomFilter.Text = '几何均值滤波';
            app.OrderFilter = uiradiobutton(app.FliterWayBtnGroup);
            app.OrderFilter.Text = '统计排序滤波';
            app.MaxFilter = uiradiobutton(app.FliterWayBtnGroup);
            app.MaxFilter.Text = '最大值滤波';
            app.MinFilter = uiradiobutton(app.FliterWayBtnGroup);
            app.MinFilter.Text = '最小值滤波';
            app.SelfAdjustFilter = uiradiobutton(app.FliterWayBtnGroup);
            app.SelfAdjustFilter.Text = '自适应维纳滤波';
            app.ActiveFilter = uiradiobutton(app.FliterWayBtnGroup);
            app.ActiveFilter.Text = '运动模糊维纳滤波';
            app.Label_16 = uilabel(app.flterway);
            app.Label_16.Text = '阶数';
            app.OrderNum = uispinner(app.flterway);
            app.OrderNum.Limits = [1 Inf];
            app.OrderNum.Value = 1;
            app.Label_17 = uilabel(app.flterway);
            app.Label_17.Text = '偏移范围';
            app.ShiftRange_2 = uispinner(app.flterway);
            app.ShiftRange_2.Limits = [2 Inf];
            app.ShiftRange_2.Value = 2;
            app.Label_18 = uilabel(app.flterway);
            app.Label_18.Text = '偏移角度';
            app.ShiftAngle_2 = uispinner(app.flterway);
            app.ShiftAngle_2.Limits = [-180 180];
            app.recoverfilterchk = uicheckbox(app.flterway);
            app.recoverfilterchk.Text = '滤波器';
            app.Tab_4 = uitab(app.TabGroup);
            app.Tab_4.Title = '图像压缩';
            app.CompressCodeTable = uitable(app.Tab_4);
            app.CompressCodeTable.ColumnName = {'哈夫曼编码'; '预测编码'};
            app.CompressCodeTable.RowName = {};
            app.Label_19 = uilabel(app.Tab_4);
            app.Label_19.Text = '压缩编码';
            app.Label_21 = uilabel(app.Tab_4);
            app.Label_21.Text = '哈夫曼编码压缩后';
            app.HuffumanCompressSize = uieditfield(app.Tab_4, 'text');
            app.HuffumanCompressSize.Editable = 'off';
            app.Label_20 = uilabel(app.Tab_4);
            app.Label_20.Text = '哈夫曼编码压缩率';
            app.HuffcompressionRatio = uieditfield(app.Tab_4, 'text');
            app.HuffcompressionRatio.Editable = 'off';
            app.Label_22 = uilabel(app.Tab_4);
            app.Label_22.Text = '原图片大小';
            app.OriginFileSize = uieditfield(app.Tab_4, 'text');
            app.OriginFileSize.Editable = 'off';
            app.Label_23 = uilabel(app.Tab_4);
            app.Label_23.Text = '预测编码压缩后';
            app.ForeseeCompressSize = uieditfield(app.Tab_4, 'text');
            app.ForeseeCompressSize.Editable = 'off';
            app.Label_24 = uilabel(app.Tab_4);
            app.Label_24.Text = '预测编码压缩率';
            app.PrecompressionRatio = uieditfield(app.Tab_4, 'text');
            app.PrecompressionRatio.Editable = 'off';
            app.Button_11 = uibutton(app.Tab_4, 'push');
            app.Button_11.ButtonPushedFcn = createCallbackFcn(app, @Button_11Pushed, true);
            app.Button_11.Text = '开始压缩';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = yinyue

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end